classdef BC_and_PWM_app_Control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Send_BC_Button            matlab.ui.control.Button
        Send_PWM_Button           matlab.ui.control.Button
        CurrentDutyCycleLabel     matlab.ui.control.Label
        display_of_slider         matlab.ui.control.NumericEditField
        BounderyControlLabel      matlab.ui.control.Label
        PWMControlLabel           matlab.ui.control.Label
        KEY2_onBoard              matlab.ui.control.Label
        KEY3_onBoard              matlab.ui.control.Label
        label3                    matlab.ui.control.Label
        Decimal_Result            matlab.ui.control.NumericEditField
        Decimal_Value_BCLabel     matlab.ui.control.Label
        UART_reading              matlab.ui.control.Button
        Label_L1_red              matlab.ui.control.Label
        DefaultzoomButton         matlab.ui.control.Button
        Plot_of_ADC_data          matlab.ui.control.UIAxes
        DutyCycleSlider_2Label    matlab.ui.control.Label
        DutyCycle_Slider          matlab.ui.control.Slider
        UIAxes                    matlab.ui.control.UIAxes
        I1SliderLabel             matlab.ui.control.Label
        I1_Slider                 matlab.ui.control.Slider
        CurrentI_1EditFieldLabel  matlab.ui.control.Label
        display_Current_I1        matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Send_BC_Button
        function Send_BC_ButtonPushed(app, event)
            dec_level_BC=UART_protocl_Send_Data_BC(app.display_Current_I1.Value);
            app.Decimal_Result.Value=dec_level_BC; %% this the decimal result for the reuqired current 

        end

        % Button pushed function: Send_PWM_Button
        function Send_PWM_ButtonPushed(app, event)
            UART_protocl_Send_Data_PWM(app.display_of_slider.Value)


        end

        % Callback function
        function Decimal_ResultValueChanged(app, event)


        end

        % Button pushed function: UART_reading
        function UART_readingButtonPushed(app, event)
           cla(app.Plot_of_ADC_data) %% clear the plot 

            ADC_data=Reading_UART_data_function(app.Decimal_Result.Value);
            normal_coffiecnt=(5/256)/(15e-3*100);
            I_1=ADC_data*normal_coffiecnt;
            t=(1/892e3)*(1:length(I_1)); 
            
            
             plot(app.Plot_of_ADC_data,t,I_1,' red -- o'); %% plot of the UART protocol
            hold(app.Plot_of_ADC_data,'on') %% hold on 
             plot(app.Plot_of_ADC_data,t,(UART_protocl_Send_Data_BC(app.display_Current_I1.Value))*normal_coffiecnt*ones(1,length(ADC_data)),'magenta'); %% plot of the limit 
%             zoom(app.Plot_of_ADC_data, 'on') %% zoom 
%           zoom(app.Plot_of_ADC_data, 'off')   
            
%             legend('decimal display');xlabel('samples');ylabel('decimal display');grid on ;grid minor;
%              yticks([0:2:255]); set(gca,'FontSize',10); %% cosmetic of the plot 

        end

        % Button pushed function: DefaultzoomButton
        function DefaultzoomButtonPushed(app, event)
%             zoom(app.Plot_of_ADC_data,'on') %start zoom mode
            %%zoom(app.Plot_of_ADC_data, 'out') %% zoom 
app.UIAxes = uiaxes(app.UIFigure);
[corn_indexed,map] = imread('corn.tif',1);
imshow(corn_indexed,map,app.Plot_of_ADC_data)
plot(app.Plot_of_ADC_data)


        end

        % Callback function
        function I_1_SliderValueChanged(app, event)
            Slider_I1_value = roundn(app.I_1_Slider.Value,2);
            
        end

        % Value changed function: DutyCycle_Slider
        function DutyCycle_SliderValueChanged(app, event)
            value_slider = app.DutyCycle_Slider.Value;
            app.display_of_slider.Value=round(value_slider);
            
            
            f=50e3;
T=1/f;

t=linspace(0,T,f);

vector_ones=ones(1,f);
Duty_Cycle=value_slider/100;

for i=1:length(vector_ones)
    if(Duty_Cycle*f<i)
        
        vector_ones(i)=0;
    end
end

plot(app.UIAxes,t,vector_ones,'red','linewidth',5);            
        end

        % Value changed function: I1_Slider
        function I1_SliderValueChanged(app, event)
            app.display_Current_I1.Value = roundn(app.I1_Slider.Value,-2);
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Send_BC_Button = uibutton(app.UIFigure, 'push');
            app.Send_BC_Button.ButtonPushedFcn = createCallbackFcn(app, @Send_BC_ButtonPushed, true);
            app.Send_BC_Button.Text = 'Send_BC';
            app.Send_PWM_Button = uibutton(app.UIFigure, 'push');
            app.Send_PWM_Button.ButtonPushedFcn = createCallbackFcn(app, @Send_PWM_ButtonPushed, true);
            app.Send_PWM_Button.Text = 'Send_PWM';
            app.CurrentDutyCycleLabel = uilabel(app.UIFigure);
            app.CurrentDutyCycleLabel.Text = 'Current Duty Cycle';
            app.display_of_slider = uieditfield(app.UIFigure, 'numeric');
            app.BounderyControlLabel = uilabel(app.UIFigure);
            app.BounderyControlLabel.Text = 'Boundery Control ';
            app.PWMControlLabel = uilabel(app.UIFigure);
            app.PWMControlLabel.Text = 'PWM Control';
            app.KEY2_onBoard = uilabel(app.UIFigure);
            app.KEY2_onBoard.Text = 'Press KEY2 on Board to confirm your choice ';
            app.KEY3_onBoard = uilabel(app.UIFigure);
            app.KEY3_onBoard.Text = 'Press KEY3 on Board to confirm your choice ';
            app.label3 = uilabel(app.UIFigure);
            app.label3.Text = 'Press Switch 5 on Board to change BC or PWM ';
            app.Decimal_Result = uieditfield(app.UIFigure, 'numeric');
            app.Decimal_Value_BCLabel = uilabel(app.UIFigure);
            app.Decimal_Value_BCLabel.Text = 'Decimal_Value_BC';
            app.UART_reading = uibutton(app.UIFigure, 'push');
            app.UART_reading.ButtonPushedFcn = createCallbackFcn(app, @UART_readingButtonPushed, true);
            app.UART_reading.Text = 'Start';
            app.Label_L1_red = uilabel(app.UIFigure);
            app.Label_L1_red.Text = 'Press Start to get ADC data for I L1';
            app.DefaultzoomButton = uibutton(app.UIFigure, 'push');
            app.DefaultzoomButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultzoomButtonPushed, true);
            app.DefaultzoomButton.Text = 'Default  zoom';
            app.Plot_of_ADC_data = uiaxes(app.UIFigure);
            title(app.Plot_of_ADC_data, {'I_1 during transiant mode'; ''})
            xlabel(app.Plot_of_ADC_data, {'t[m Sec]'; ''})
            ylabel(app.Plot_of_ADC_data, {'I_1 [A]'; ''})
            app.Plot_of_ADC_data.XLim = [0 0.0023];
            app.Plot_of_ADC_data.YLim = [-0.1 4];
            app.Plot_of_ADC_data.ZLim = [0 4];
            app.Plot_of_ADC_data.XTick = [0 0.0001 0.0002 0.0003 0.0004 0.0005 0.0006 0.0007 0.0008 0.0009 0.001 0.0011 0.0012 0.0013 0.0014 0.0015 0.0016 0.0017 0.0018 0.0019 0.002 0.0021 0.0022 0.0023];
            app.Plot_of_ADC_data.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'};
            app.Plot_of_ADC_data.YTick = [-0.1 0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4];
            app.Plot_of_ADC_data.YTickLabel = {'-0.1'; '0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'; '1.1'; '1.2'; '1.3'; '1.4'; '1.5'; '1.6'; '1.7'; '1.8'; '1.9'; '2'; '2.1'; '2.2'; '2.3'; '2.4'; '2.5'; '2.6'; '2.7'; '2.8'; '2.9'; '3'; '3.1'; '3.2'; '3.3'; '3.4'; '3.5'; '3.6'; '3.7'; '3.8'; '3.9'; '4'};
            app.DutyCycleSlider_2Label = uilabel(app.UIFigure);
            app.DutyCycleSlider_2Label.Text = 'Duty Cycle Slider';
            app.DutyCycle_Slider = uislider(app.UIFigure);
            app.DutyCycle_Slider.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.DutyCycle_Slider.MajorTickLabels = {'0', '5', '10', '15', '20', '25', '30', '35', '40', '45', '50', '55', '60', '65', '70', '75', '80', '85', '90', '95', '100', ''};
            app.DutyCycle_Slider.ValueChangedFcn = createCallbackFcn(app, @DutyCycle_SliderValueChanged, true);
            app.DutyCycle_Slider.MinorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'PWM'; ' f=50[KHz]'})
            xlabel(app.UIAxes, '\mu Sec')
            ylabel(app.UIAxes, 'PWM')
            app.UIAxes.XLim = [0 2e-05];
            app.UIAxes.XTick = [0 1e-06 2e-06 3e-06 4e-06 5e-06 6e-06 7e-06 8e-06 9e-06 1e-05 1.1e-05 1.2e-05 1.3e-05 1.4e-05 1.5e-05 1.6e-05 1.7e-05 1.8e-05 1.9e-05 2e-05];
            app.UIAxes.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'};
            app.I1SliderLabel = uilabel(app.UIFigure);
            app.I1SliderLabel.Text = 'I1 Slider';
            app.I1_Slider = uislider(app.UIFigure);
            app.I1_Slider.Limits = [0 3];
            app.I1_Slider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3];
            app.I1_Slider.MajorTickLabels = {'0', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1', '1.1', '1.2', '1.3', '1.4', '1.5', '1.6', '1.7', '1.8', '1.9', '2', '2.1', '2.2', '2.3', '2.4', '2.5', '2.6', '2.7', '2.8', '2.9', '3'};
            app.I1_Slider.ValueChangedFcn = createCallbackFcn(app, @I1_SliderValueChanged, true);
            app.CurrentI_1EditFieldLabel = uilabel(app.UIFigure);
            app.CurrentI_1EditFieldLabel.Text = 'Current I_1';
            app.display_Current_I1 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BC_and_PWM_app_Control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end